classdef O2Kv09 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        TabGroup                       matlab.ui.container.TabGroup
        ImportTab                      matlab.ui.container.Tab
        ImportTabGridLayout            matlab.ui.container.GridLayout
        SheetPreviewPanel              matlab.ui.container.Panel
        SheetPreviewPanelGridLayout    matlab.ui.container.GridLayout
        PreviewData                    matlab.ui.control.Table
        ImportSelectaSheetPanel        matlab.ui.container.Panel
        ImportSelectaSheetPanelGridLayout  matlab.ui.container.GridLayout
        ImportSheetsDropDown           matlab.ui.control.DropDown
        CurrentFilePanel               matlab.ui.container.Panel
        CurrentFilePanelGridLayout     matlab.ui.container.GridLayout
        NocurrentfileLabel             matlab.ui.control.Label
        ExportforJamoviButton          matlab.ui.control.Button
        GotoComplexPlotsButton         matlab.ui.control.Button
        GotoGeneralBarPlotButton       matlab.ui.control.Button
        ImportafileButton              matlab.ui.control.Button
        GeneralBarPlotTab              matlab.ui.container.Tab
        GridGeneralBarPlotGridLayout   matlab.ui.container.GridLayout
        GeneralPlotOptionsPanel        matlab.ui.container.Panel
        GeneralPlotOptionsGridLayout   matlab.ui.container.GridLayout
        AdditionalComplexesPanel       matlab.ui.container.Panel
        AdditionalComplexesPanelGridLayout  matlab.ui.container.GridLayout
        ETSCheckBox                    matlab.ui.control.CheckBox
        TotalNetOXPHOSCheckBox         matlab.ui.control.CheckBox
        ComplexIILinkedETSCapacityCheckBox  matlab.ui.control.CheckBox
        ComplexINetOXPHOSCheckBox      matlab.ui.control.CheckBox
        GeneralPlotSelectaSheetPanel   matlab.ui.container.Panel
        GeneralPlotSelectaSheetPanelGridLayout  matlab.ui.container.GridLayout
        GeneralPlotSheetSelection      matlab.ui.control.DropDown
        PlotOptionsPanel               matlab.ui.container.Panel
        PlotOptionsPanelGridLayout     matlab.ui.container.GridLayout
        PlotMultipleLinesCheckBox      matlab.ui.control.CheckBox
        ShowErrorBarsCheckBox          matlab.ui.control.CheckBox
        GeneralExportPlotButton        matlab.ui.control.Button
        GeneralGeneratePlotButton      matlab.ui.control.Button
        General_UIAxes                 matlab.ui.control.UIAxes
        ComponentPlotsTab              matlab.ui.container.Tab
        ComponentPlotGridLayout        matlab.ui.container.GridLayout
        ComponentsPlotPanel            matlab.ui.container.Panel
        ComponentsPanelGridLayout      matlab.ui.container.GridLayout
        CohortsDisplayPanel            matlab.ui.container.Panel
        CohortsDisplayPanelGridLayout  matlab.ui.container.GridLayout
        CohortsDisplayListBox          matlab.ui.control.ListBox
        ComplexesDisplayPanel          matlab.ui.container.Panel
        ComplexesDisplayPanelGrdidLayout  matlab.ui.container.GridLayout
        ComponentsDisplayListBox       matlab.ui.control.ListBox
        ComponentPlotsSelectaSheetPanel  matlab.ui.container.Panel
        ComponentPlotsSelectaSheetPanelGridLayout  matlab.ui.container.GridLayout
        ComponentPlotSheetSelection    matlab.ui.control.DropDown
        ComponentsExportPlotButton     matlab.ui.control.Button
        ComponentsGeneratePlotButton   matlab.ui.control.Button
        Component_UIAxes_1             matlab.ui.control.UIAxes
        Component_UIAxes_2             matlab.ui.control.UIAxes
        Component_UIAxes_3             matlab.ui.control.UIAxes
        Component_UIAxes_4             matlab.ui.control.UIAxes
        Component_UIAxes_5             matlab.ui.control.UIAxes
        Component_UIAxes_6             matlab.ui.control.UIAxes
        Component_UIAxes_7             matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        selectedFile = false ;% File that's imported
        differentSheets % Sheets from imported file
        dataTable % Cleaned/Altered Data based on the specific calculation
        pValues = false; % P-Values calculated from the dataTable
        pValueTextHandles % Handles for the text values of P
        metaData % Raw-Nonspecifc Data from the sheet
        barChartHandles = [] % Data from the Bar Chart Creation
        plotLineHandles = [] % Handles the plot Line object
        plotLineDrawn = false% Bool detecting whether or not a plot line has been drawn
        errorBarHandles = [] % Handles for the Error bars object
        generalScatterPlotHandles = [] % Handles for the Scatter Plot
        whichLinesPlotted = [] % Stores indexs for which cohorts have had lines plotted;
        currentComponents % Which components are selected for the components tab
        componentUIAxesArray = []
        componentPlotHandles = [] % Handles for the component plots
        activeComponentAxes % Handles for the UI Axes objects with children
        exportAxes % Axes selected for export
    end
    
    methods (Access = private)
        
        function importData = importingO2K(app, file, sheet) % Imports the Initial Data from the O2K worksheet file
            filename = file;
            opts = detectImportOptions(filename, "VariableNamingRule", "preserve", "Sheet", sheet); % Extracts the file
            metadata = readtable(filename, opts); % Writes the selected sheet to a table

            % Filter out the columns with 'ROX' coupling control states (no useful data
            % in these columns)
            row1_values = metadata{1, :}; % Extract the first row (cell array)
            is_rox_column = cellfun(@(x) strcmpi(x, 'ROX'), row1_values); % Logical array for 'ROX' columns
            metadata(:, is_rox_column) = []; % Delete the columns

            % Identify columns with only empty data or empty strings, starting from row 3 (check for columns
            % where all values are empty or 0x0 char)
            data_starting_from_row_3 = metadata{3:end, :};
            empty_columns = varfun(@(x) all(cellfun(@(y) isempty(y) || (ischar(y) && strcmp(y, '')), x)), ...
                array2table(data_starting_from_row_3), 'OutputFormat', 'uniform');

            % Remove these empty columns
            metadata(:, empty_columns) = [];

            % Extract the current column names (these correspond to the original Row 1 in your Excel file)
            current_col_names = metadata.Properties.VariableNames;

            % Add these current column names as a new row in the table
            metadata = [array2table(current_col_names, 'VariableNames', current_col_names); metadata];

            % Extract the second row (new intended column names)
            row2 = string(metadata{2, :});

            % Concatenate the current column names with the second row to create new column names
            new_col_names = current_col_names; % Start with the current column names
            for i = 1:length(current_col_names)
                if ~startsWith(current_col_names{i}, "Var") % Only concatenate if the name doesn't start with "Var"
                    new_col_names{i} = char(row2{i} + "-" + string(current_col_names{i}));
                else
                    new_col_names{i} = char(row2{i}); % Keep as character vector
                end
            end

            % Set the new concatenated column names
            metadata.Properties.VariableNames = new_col_names;

            % Remove the first row used to store the original column names
            metadata(2, :) = [];

            % Filter out rows with any empty strings or missing values
            metadata = metadata(~any(ismissing(metadata), 2), :);  % Remove rows with missing values
            metadata = metadata(~any(metadata{:, :} == "", 2), :);  % Remove rows with empty strings

            % Correct data types
            % Convert ID column to integer (ensure it's numeric before conversion)
            if iscell(metadata.("ID#"))  % If ID column is in cell array of strings
                metadata.("ID#") = str2double(metadata.("ID#"));  % Convert from string to numeric
            end
            metadata.("ID#") = int32(metadata.("ID#"));  % Change the type to int32 (or int64 if needed)

            % Convert Date column to datetime
            metadata.Date = datetime(metadata.Date, 'InputFormat', 'dd-MMM-yyyy');  % Adjust format if needed

            % Convert Sample number and Subsample number to integers (assuming these are in columns 'Sample number' and 'Subsample number')
            if iscell(metadata.("Sample #"))  % If Sample number column is in cell array of strings
                metadata.("Sample #") = str2double(metadata.("Sample #"));  % Convert from string to numeric
            end
            metadata.("Sample #") = int32(metadata.("Sample #"));

            if iscell(metadata.("Subsample #"))  % If Subsample # column is in cell array of strings
                metadata.("Subsample #") = str2double(metadata.("Subsample #"));  % Convert from string to numeric
            end
            metadata.("Subsample #") = int32(metadata.("Subsample #"));

            % Grabbing the unique cohorts to use for the component plot
            cohorts = string(table2cell(metadata(3:end, "Cohort")));
            uniqueCohorts = unique(cohorts);
            app.CohortsDisplayListBox.Items = uniqueCohorts;

            % Returning metadata
            importData = metadata;
            
            
        end

        function cleanData = generalPlotDataCleaning(app, metadata, sheet) % Cleaning and preparing the data for the general plot
            % Find columns where the second row contains the sheet name
            second_row = metadata(2, :); % Extract the second row as a table
            second_row_values = table2cell(second_row);
            is_sheetname_column = contains(string(second_row_values), sheet, 'IgnoreCase', true);
            sheetname_column_indices = find(is_sheetname_column);

            % Get the column names corresponding to the sheet name
            chemicals_titrations = metadata.Properties.VariableNames(sheetname_column_indices);
            %num_chemicals = length(chemicals_titrations);
            selected_columns = ["ID#", "Cohort", chemicals_titrations];

            % Extract the relevant columns
            data = metadata(3:end, selected_columns);
            
            % Adding a Complex 1 Net OXPHOS Column (if the checkmark is
            % checked)
            if app.ComplexINetOXPHOSCheckBox.Value
                pfiMark = str2double(data{:, "REN-pfi"});
                adpMark = str2double(data{:, "N_P-2D"}); 
                Complex_I_Net_OXPHOS = adpMark - pfiMark; % Subtracting out the pfi Mark (the permeabalized fibers)
                data.Complex_I_Net_OXPHOS = cellstr(string(Complex_I_Net_OXPHOS(:,:)));
                data.Properties.VariableNames{end} = 'Complex I Net OXPHOS';
            end

            % Adding a Complex II Linked ETS Capacity Column (if the checkmark is checked)
            if app.ComplexIILinkedETSCapacityCheckBox.Value
                data.Complex_II_linked_ETS_Capacity = data{:, "S_E-5Rot"};
                data.Properties.VariableNames{end} = 'Complex II Linked ETS Capacity';
            end

            % Adding a Total Net OXPHOS Column (if the checkmark is checked)
            if app.TotalNetOXPHOSCheckBox.Value
                pfiMark = str2double(data{:,"REN-pfi"}); 
                succinateMark = str2double(data{:, "NS_P-3S"}); 
                Total_Net_OXPHOS = succinateMark - pfiMark; % Subtracting out the pfi Mark (the permeabalized fibers)
                height(Total_Net_OXPHOS)
                height(data)
                data.Total_Net_OXPHOS = cellstr(string(Total_Net_OXPHOS(:,:)));
                data.Properties.VariableNames{end} = 'Total Net OXPHOS';
            end

            % Adding an ETS Column (if the checkmark is checked)
            if app.ETSCheckBox.Value
                data.Electron_Transport_System = data{:,"NS_E-4U"};
                data.Properties.VariableNames{end} = 'Electron Transport System Capacity';
            end
            cleanData = data;
        end

        function generalPlotGeneration(app, data, sheet, ax) % Generates the Plot for the general graph
            % Extract the cohorts and titration data
            cohorts = string(data.Cohort); % Convert cohort column to string array
            titration_columns = data(:, 3:end); % Extract titration step data (excluding ID# and Cohort)

            % Convert cell arrays in titration columns to numeric arrays
            for col = 1:width(titration_columns)
                if iscell(titration_columns{:,col})
                    titration_columns.(col) = cellfun(@str2double, titration_columns.(col));
                end
            end

            % Compute mean FCR values for each cohort and titration step
            unique_cohorts = unique(cohorts); % Unique cohort names ('aCSF', '0Mg')
            num_cohorts = numel(unique_cohorts); % Number of unique cohorts
            titration_steps = titration_columns.Properties.VariableNames; % Extract titration step names
            num_titrations = numel(titration_steps); % Number of titration steps
            
            % Initialize matrix for mean FCR values
            mean_sheet= zeros(num_titrations, num_cohorts);
            sem_sheet = zeros(num_titrations, num_cohorts);
          

            % Loop over each titration step and cohort to compute mean FCR,
            % as well as perfoming an ANOVA test on each titration step
            anovaResults = zeros(1, num_titrations);
            for i = 1:num_titrations
                anovaData = []; % Storing the Data to generate anova values
                anovaGroups = []; % Anova Groups
                for j = 1:num_cohorts
                    % Filter rows for the current titration step and cohort
                    cohort_mask = cohorts == unique_cohorts(j);

                    % Extract data for the current titration step and cohort
                    titration_data = cellfun(@str2double, data{cohort_mask, titration_steps{i}});
                    anovaData = [anovaData; titration_data];
                    anovaGroups = [anovaGroups; repelem(j, length(titration_data))'];
                    % Compute mean and SEM FCR for this group
                    mean_sheet(i, j) = mean(titration_data, 'omitnan');
                    sem_sheet(i, j) = std(titration_data, 'omitnan') / sqrt(sum(~isnan(titration_data)));
                end
                [p] = anova1(anovaData, anovaGroups, 'off');
                anovaResults(i) = p;
            end
            
            % Cleaning the Anova Results Data
            anovaSignficance = cell(size(anovaResults));
            anovaSignficance(anovaResults < .05) = {"*"};
            anovaSignficance(anovaResults < .01) = {"**"};
            anovaSignficance(anovaResults < .001) = {"***"};

            % Create the grouped bar chart with error bars;
            cla(ax, 'reset');
            b = bar(ax, mean_sheet, 'grouped'); % Store the bar chart
            set(b, 'FaceAlpha', 1);
            set(b, 'HitTest', 'off');
            app.barChartHandles = b;
            hold(ax, 'on');

            % Get the x-coordinates of the bars
            x_positions = nan(size(mean_sheet)); % PreSallocate for x-positions
            for i = 1:num_titrations
                for j = 1:num_cohorts
                    x_positions(i, j) = b(j).XEndPoints(i); % Extract bar center positions
                end
            end
           

            % Anova signficance bar generation
            anovaBarX = x_positions(~cellfun(@isempty, anovaSignficance), [1,num_cohorts])';
            anovaBarY = mean_sheet(~cellfun(@isempty, anovaSignficance), :);
            anovaBarY = cellfun(@max, num2cell(anovaBarY, 2));
            anovaBarY = anovaBarY + (.1 * anovaBarY);
            anovaBarY = repmat(anovaBarY, 1 ,2)';
            anovaLabels = anovaSignficance(~cellfun(@isempty, anovaSignficance))';
            plot(ax, anovaBarX, anovaBarY, 'k|-', 'LineWidth',1.5);
            mean(anovaBarX, 1);
            mean(anovaBarY, 1);
            text(ax, mean(anovaBarX, 1), mean(anovaBarY, 1), anovaLabels, ...
                'HorizontalAlignment', 'center', ...
                'VerticalAlignment', 'bottom', ...
                'Color','k',...
                'FontWeight', 'bold', 'FontSize', 20);

            % Add error bars
            if app.ShowErrorBarsCheckBox.Value
                errorBarVisibility = 'on';
            else
                errorBarVisibility = 'off';
            end

            app.errorBarHandles = gobjects(1, num_cohorts); % Handle Array preallocation

            for j = 1:num_cohorts
                app.errorBarHandles(j) = errorbar(ax,b(j).XEndPoints, mean_sheet(:, j), sem_sheet(:, j), 'k', ...
                    'linestyle', 'none', 'LineWidth', 1.5, 'Visible', errorBarVisibility);
            end
            
            % Overlay individual data points
            point_color = [0.25, 0.25, 0.25]; % Uniform gray color for points
            app.generalScatterPlotHandles = gobjects(num_cohorts, num_titrations); % Initializing the scatterPlotHandlers to the correct size
            app.whichLinesPlotted = zeros(num_cohorts,1);
            for i = 1:num_titrations
                for j = 1:num_cohorts
                    % Filter rows for the current cohort
                    cohort_mask = cohorts == unique_cohorts(j);

                    % Extract data for the current titration step and cohort
                    titration_data = cellfun(@str2double, data{cohort_mask, titration_steps{i}});
                    % Scatter individual data points centered over the corresponding bar
                    h = scatter(ax,repmat(x_positions(i, j), numel(titration_data), 1), ...
                        titration_data, 50, 'MarkerEdgeColor', point_color, ...
                        'MarkerFaceColor', 'none');
                    axis(ax, 'padded');
                    h.ButtonDownFcn = @(src, event) generalPlotPointLine(app, src, event, x_positions, data, j);
                    h.PickableParts = 'all';
                    h.HitTest = 'on';
                    %h.MarkerFaceColor = 'flat';
                    app.generalScatterPlotHandles(j,i) = h;
                end
            end
            

            hold(ax, 'off');

            % Customize the bar chart
            ax.ButtonDownFcn = @(src, event) resetGeneralPlot(app, event);
            ax.PickableParts = 'all';
            ax.Color = "w";
            ax.GridColor = [0.5 0.5 0.5];
            xticks(ax,1:num_titrations); % Set x-tick positions
            xticklabels(ax,titration_steps); % Label x-ticks with titration step names
            xlabel(ax,'Coupling Control State');
            ylabel(ax,sheet);
            title(ax,sheet);
            legend(ax,unique_cohorts, 'Location', 'northwest'); % Add legend with cohort names
            grid(ax,'on');
        end
        
        function generalPlotPointLine(app, ~, event, xValues, yValues, curCohort) % Generates the lines when a point 
            % on the general plot is selected

            % Resetting the lines and barchart every time the function is called, or if
            % the "Plot multiple lines" checkbox is unchecked
            if app.plotLineDrawn == true
                resetGeneralPlot(app, []);
            end 
            
            % Making sure the Axis hold is set correctly
            if ~ishold(app.General_UIAxes)
                hold(app.General_UIAxes, 'on')
            end

            % Recording which Cohort this line is
            app.whichLinesPlotted(curCohort) = 1;


            % Fading the Bars for the Barchart and formatting the other
            % scatter plots as needed
            set(app.barChartHandles, 'FaceAlpha', .3);
            colorOrder = app.General_UIAxes.ColorOrder;
            markerColor = colorOrder(curCohort, :);
            lineColor = markerColor * .5;
            set(app.generalScatterPlotHandles(:,:), 'MarkerEdgeAlpha', .3);
            

            % Getting the Coordinates for the point selected
            coords = event.IntersectionPoint;
            xCoord = coords(1);
            yCoord = coords(2);
            tolerance = 1e-4;

            % Creating numeric Matrices
            xMatrix = xValues(:,:);
            yMatrix = str2double(yValues{:,3:end});

            % Find the corresponding X coordinates for the rest of the
            % matching values
            [~, xCol] = find(abs(xMatrix - xCoord) < tolerance);

            % Find the corresponding Y coordinates for the rest of the
            % matching values
            [yRow,~] = find(abs(yMatrix- yCoord) < tolerance);

            % Generate Label for the line
            if yRow ~= 1
                pointName = "ID: " + string(yValues{yRow(1),1}) + " Cohort: " + string(yValues{yRow(1),2});
            else
                pointName = "Multiple points in the same location";
            end

            % Plot the Line
             h = plot(app.General_UIAxes, xMatrix(:,xCol), yMatrix(yRow(1),:),'-o', ...
             'DisplayName', pointName, 'LineWidth', 1.5, 'Color', lineColor);

            % Store the Object handles
            app.plotLineHandles = [app.plotLineHandles, h];
            app.plotLineDrawn = true;

            % Turn Hold back off (if necessary)
            if ~ishold(app.General_UIAxes)
                hold(app.General_UIAxes, 'off');
            end
        end
        
        function resetGeneralPlot(app, event) % Helper function that resets the state of the general plot, 
            % returning the scatter plots and bars to normal
            if app.PlotMultipleLinesCheckBox.Value && isempty(event) % Only resets the scatter plots if Plot Multiple Lines is checked
                set(app.generalScatterPlotHandles(:,:), 'MarkerEdgeAlpha', 1);
                set(app.barChartHandles, 'FaceAlpha', 1);
                
            else % Resets the scatter plots and deletes the handles for the lines
                set(app.generalScatterPlotHandles(:,:), 'MarkerEdgeAlpha', 1);
                set(app.barChartHandles, 'FaceAlpha', 1);
                delete (app.plotLineHandles);
                app.whichLinesPlotted(:) = 0;
                app.plotLineHandles = [];
                app.plotLineDrawn = false;
                set(app.generalScatterPlotHandles(:,:), 'LineWidth', .5);
                set(app.generalScatterPlotHandles(:,:), 'MarkerEdgeColor', [0.25, 0.25, 0.25]);
                set(app.generalScatterPlotHandles(:,:), 'MarkerFaceColor', 'none');
            end
        end
        
        function results = componentPlotDataCleaning(app, metadata) % Data cleaning/preparation for the component plot
            % Copy the relevant columns from the metadata table
            complexData = metadata(3:end, ["ID#", "Cohort", "Sample #"]);

            % Check which complexes are enables and add/perform their
            % associated operations to add them to the complex table tab

            
            % Checks if the selected components contains "Complex I-linked
            % LEAK respiration" and grabs the relevant column
            if any(contains(app.currentComponents, "Complex I-linked LEAK Respiration"))
                complexData.Leak = metadata{3:end, "N_L-1GM"};
                complexData.Properties.VariableNames{end} = 'Complex I-linked Leak Respiration';
            end

            % Checks if the selected components contains "Complex I-linked OXPHOS Capacity" and 
            % grabs the relevant column
            if any(contains(app.currentComponents, "Complex I-linked OXPHOS Capacity"))
                complexData.Complex_I_linked_OXPHOS_Capacity = metadata{3:end, "N_P-2D"};
                complexData.Properties.VariableNames{end} = 'Complex I-linked OXPHOS Capacity';
            end

            % Checks if the selected components contains "Total OXPHOS Capacity"
            % and grabs the relevant column
            if any(contains(app.currentComponents, "Total OXPHOS Capacity"))
                complexData.TotalOXPHOS = metadata{3:end, "NS_P-3S"};
                complexData.Properties.VariableNames{end} = 'Total OXPHOS Capacity';
            end

            % Checks if the selected components contains "Complex I Net OXPHOS"
            % and grabs the relevant column, subtracting out the leak
            if any(contains(app.currentComponents, "Complex I Net OXPHOS"))
                pfiMark = str2double(metadata{3:end, "REN-pfi"});
                adpMark = str2double(metadata{3:end, "N_P-2D"}); 
                Complex_I_Net_OXPHOS = adpMark - pfiMark; % Subtracting out the pfi Mark (the permeabalized fibers)
                complexData.Complex_I_Net_OXPHOS = cellstr(string(Complex_I_Net_OXPHOS(:,:)));
                complexData.Properties.VariableNames{end} = 'Complex I Net OXPHOS';
            end

            % Checks if the selected components contains "Complex II linked ETS Capacity"
            % and grabs the relevant column, subtracting out the leak
            if any(contains(app.currentComponents, "Complex II linked ETS Capacity"))
                complexData.Complex_II_linked_ETS_Capacity = metadata{3:end, "S_E-5Rot"};
                complexData.Properties.VariableNames{end} = 'Complex II linked ETS Capacity';
            end

            % Checks if the selected components contains "Total Net OXPHOS"
            % and grabs the relevant column, subtracting out the leak
            if any(contains(app.currentComponents, "Total Net OXPHOS"))
                pfiMark = str2double(metadata{3:end,"REN-pfi"}); 
                succinateMark = str2double(metadata{3:end, "NS_P-3S"}); 
                Total_Net_OXPHOS = succinateMark - pfiMark; % Subtracting out the pfi Mark (the permeabalized fibers)
                complexData.TotalNetOXPHOS = cellstr(string(Total_Net_OXPHOS(:,:)));
                complexData.Properties.VariableNames{end} = 'Total Net OXPHOS';
            end

            % Checks if the selected components contains "Electron Transport System Capacity"
            % and grabs the relevant column, subtracting out the leak
            if any(contains(app.currentComponents, "Electron Transport System Capacity"))
                complexData.Electron_Transport_System = metadata{3:end,"NS_E-4U"};
                complexData.Properties.VariableNames{end} = 'Electron Transport System Capacity';
            end

            results = complexData;
        end
        
        function results  = complexPlotGeneration(app, complexData, ax) % Component plot generation
            
            % Generating the unique Cohorts list to use for iteration
            cohorts = string(complexData.Cohort);
            selectedCohorts = app.CohortsDisplayListBox.Value;
            cohortNum = numel(selectedCohorts);

            % Filtering complexData into a new list with only the selected
            % Cohorts
            selectedCohortsMask = ismember(cohorts,selectedCohorts);

            % Generating the sample list, and the unique sample list to use
            % for iteration
            complexColumns = complexData(:, 4:end);
            samples = complexData.("Sample #");
            uniqueSamples = unique(samples);
            
            % Preparing the Array of UI axes objects;
            cla(ax(:));
            arrayfun(@(a) set(a, 'Visible', 'off'), ax);  % hides axes box and ticks
            arrayfun(@(a) set(a.Children, 'Visible', 'off'), ax);  % hides children
            arrayfun(@(a) set(a.Title, 'Visible', 'off'), ax); % hides the title
            arrayfun(@(a) set(a.XLabel, 'Visible', 'off'), ax); % hides the x-axis title
            arrayfun(@(a) set(a.YLabel, 'Visible', 'off'), ax); % hides the y-axis title
            arrayfun(@(a) legend(a, 'hide'), ax);

            % Resizing based on the number of UI Axes figures
            componentsTabDynamicAdjustment(app, width(complexColumns));

            % Ensuring that every column in Complex Columns is a double
            for col = 1:width(complexColumns)
                if iscell(complexColumns{:,col})
                    complexColumns.(col) = cellfun(@str2double, complexColumns.(col));
                end
            end

            % Preallocating the cell array for handling the complexes
            complexPlotHandles = cell(1,width(complexColumns));
            
            % Generating and plotting the paired values
            for j = 1:width(complexColumns) % Iterating over every selected complex
                currentUIAxes = ax(j);
                hold(currentUIAxes, "on");
                disableDefaultInteractivity(currentUIAxes);
                h = gobjects(1, numel(uniqueSamples));
                for k = 1:numel(uniqueSamples) % Iterating over every sample
                    sampleMask = samples == uniqueSamples(k); % Masks to just the current sample
                    combinedMask = sampleMask & selectedCohortsMask;
                    sampleCohorts = complexData.Cohort(combinedMask); % Masks the cohorts to just the ones within the current sample
                    [~, index] = ismember(sampleCohorts, selectedCohorts); % Gets the index of the selected Cohorts within the sample cohort list;
                    plotXValues = index; % Uses the previous indices for the x location
                    plotYValues = complexColumns{combinedMask, j}; % Masks the complexColumns at the current J column to just the sample's Y's
                    sampleIDs= complexData.("ID#")(combinedMask); % Generating the ID numbers of the relavent sample ID's using the mask
                    plotName = "ID#: " + strjoin(string(sampleIDs), ", "); % Creating a display name using the sampleIDs variable
                    pairedXYValues = [plotXValues,plotYValues]; % Unites the X & Y values
                    xySorted = sortrows(pairedXYValues, 1); % Sorts the values so they're in the same order for every complex
                    l = plot(currentUIAxes, xySorted(:,1), xySorted(:,2), '-', 'Color', "black", ...
                         'LineWidth', 1.5, 'DisplayName', plotName);
                    l.ButtonDownFcn = @(src, event) complexLineClicked(app, src);
                    l.PickableParts = 'all'; 
                    l.SelectionHighlight = 'off';
                    l.HitTest = "on";
                    h(k) = l;
                end
                
                complexPlotHandles{1, j} = h;
                
                for i = 1:cohortNum % Iterates over the cohorts to generate the scatter plots underlying the line plots
                    colorOrder = currentUIAxes.ColorOrder; % Ensuring the colors of the scatter plots match across different axes
                    markerColor = colorOrder(i, :);
                    cohortMask = cohorts == selectedCohorts(i); % Masking to get just the current Cohort
                    currentComplexValues = complexColumns{cohortMask, j}; % Generating y-values from that mask
                    q = scatter(currentUIAxes, i, currentComplexValues, ...
                        'MarkerFaceColor', markerColor, 'MarkerEdgeColor','black', 'LineWidth',1.5);
                    set(q, 'HitTest', 'off');
                    set(q, 'PickableParts', 'none');
                end


                % Customizing the chart
                currentUIAxes.Title.Visible = 'on'; % Shows the title
                title(currentUIAxes,complexColumns.Properties.VariableNames{j}); % Sets the title to the current complex
                currentUIAxes.ButtonDownFcn = @(src,event) resetComplexPlots(app); % Adds the call for the reset function
                currentUIAxes.PickableParts = 'all';
                axis(currentUIAxes, 'padded');
                currentUIAxes.Color = "w"; % Sets the axes background color to white
                currentUIAxes.GridColor = [0.5 0.5 0.5]; % Sets the axes grid color to grey
                xticks(currentUIAxes, 1:cohortNum); % Set x-tick positions
                xticklabels(currentUIAxes,selectedCohorts); % Label x-ticks with the cohort names
                xlabel(currentUIAxes,"Cohorts"); % Label the x-axis "Cohorts"
                currentUIAxes.XLabel.Visible = 'on'; % Shows the x-axis label
                xtickangle(currentUIAxes, 45); % Angles the x-axis labels for space
                xlim(currentUIAxes, [0, (1+cohortNum)]) % Provides Padding on the x-axis
                ylabel(currentUIAxes,app.ComponentPlotSheetSelection.Value); % labels the y-axis with the selected sheet
                currentUIAxes.YLabel.Visible = 'on'; % Shows the y-axis label
                grid(currentUIAxes, "on") % Shows the grid
                currentUIAxes.Visible = "on"; % Shows the axes
            end
            
            results = complexPlotHandles;

        end
        
        function componentsTabDynamicAdjustment(app, componentNum) % Dynamically sizes the axes on the components tab based on the 
            % number of components
            
            % Reset all the components, the panel, and the grid
            for i = 1:numel(app.componentUIAxesArray) % Iterates over the UI Axes objects and moves them to the first column
                   app.componentUIAxesArray(i).Layout.Column = 1;
            end
            app.ComponentsPlotPanel.Layout.Column = 1; % Shrinks the columns of the grid layout down to one
            app.ComponentPlotGridLayout.ColumnWidth = {'1x'}; % Sets the size of the column to the be the whole panel
            for k = 2:componentNum % Iterates over the number of components selected to be graphed,
                app.ComponentPlotGridLayout.ColumnWidth{k} = '1x';
                app.componentUIAxesArray(k).Layout.Column = k;
            end

            app.ComponentsPlotPanel.Layout.Column = [1 k]; % Stretches the Panel across the columns
        end
        
        function complexLineClicked(app, src) % Highlights the line clicked and creates a legend on the axes of the line selected
            handles = app.componentPlotHandles; % Data structure containing the the handles for each line, sorted by complex
            for i = 1:numel(handles) % Iterating over each array within the handles array
                legend(app.componentUIAxesArray(i), 'off') % Resets the legend
                for j = 1:numel(handles{i}) % Iterating over each handle within the subarray
                    if handles{i}(j) == src % If the handle is the one that was clicked, save it's index and use if for the legend
                        objIndex = j;
                        legend(app.componentUIAxesArray(i), src, src.DisplayName, 'Location', 'northwest');
                    else % Otherwise, decrease its handle
                        handles{i}(j).Color = [0, 0, 0, .3];
                    end
                end
            end
            for i = 1:numel(handles) % Iterate over each complex and turn the lines corresponding to ID's of the line selected
                % back to being opaque
                handles{i}(objIndex).Color = [0,0,0,1];
            end

        end
        
        function activeComponentAxesUpdator(app) % Updates the "activeComponentAxes" property based on what axes have objects drawn on them on
            % Component Tab
            allAxes = findobj(app.ComponentPlotGridLayout, 'Tag', 'UIAxes'); % Gets the handles for every object with the UIAxes tag
            app.activeComponentAxes = allAxes(~arrayfun(@(h) isempty(h.Children), allAxes)); % Sets app.activeComponentAxes equal to whatever axes have 
            % anything on them (as represented by the children property)
            
        end
        
        function resetComplexPlots(app) % Resets the Legend and the objects within the component UI axes to their default states
            handles = app.componentPlotHandles;
            for i = 1:numel(handles) % Iterates over every active complex
                legend(app.componentUIAxesArray(i), 'off');
                for j = 1:numel(handles{i}) % Iterates over every handle within each subarray and reset it to opaque
                    handles{i}(j).Color = [0,0,0,1];
                end
            end
            
        end
        
        function reset(app) % Function called when a new file is imported
            % Resetting the dropdowns
            app.ImportSheetsDropDown.Items = {''};
            app.ImportSheetsDropDown.Value = app.ImportSheetsDropDown.Items{1};
            app.GeneralPlotSheetSelection.Items = {''};
            app.GeneralPlotSheetSelection.Value = app.ImportSheetsDropDown.Items{1};
            app.ComponentPlotSheetSelection.Items = {''};
            app.ComponentPlotSheetSelection.Value = app.ComponentPlotSheetSelection.Items{1};

            % Clearing and hiding all of the UI Axes objects
            
            % General Tab UI Axes object
            cla(app.General_UIAxes, 'reset');
            app.General_UIAxes.Visible = 'off';
            % Component Tab UI Axes object
            compAxes = findobj(app.ComponentPlotsTab, 'Tag', 'UIAxes');
            for i = 1:numel(compAxes)
                if compAxes(i).Visible == "on"
                    cla(compAxes(i));
                    compAxes(i).Visible = 'off';
                    compAxes(i).Title.Visible = 'off';
                    compAxes(i).XLabel.Visible = 'off';
                    compAxes(i).YLabel.Visible = 'off';
                   
                end
            end

        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            warning('off', 'MATLAB:table:ModifiedAndSavedVarnames'); % Turns off the warning that is generated when you import
            % a spreadsheet and convert it into a table in MATLAB

            % Dynamically resizes the screen based on the screensize of the
            % user
            screenSize = get(0, 'ScreenSize');
            app.UIFigure.Position = [(screenSize(3) - (screenSize(3) / 1.2))/2, ...
                (screenSize(4) - (screenSize(4) / 2))/2, (screenSize(3) / 1.2), ...
                (screenSize(4) / 2)];

            % Set the currentComponents property based on Component
            app.currentComponents = [app.ComponentsDisplayListBox.Value];

        end

        % Button pushed function: ImportafileButton
        function ImportafileButtonPushed(app, event)
                % Indicate visually that a file is being loaded
                app.NocurrentfileLabel.Text = "Loading...";
                
                % Get the file the user selected
                [file, path] = uigetfile({'*.xlsx', 'All Files'}, 'Select a File');

                % Bringing the figure back to the front
                figure(app.UIFigure);
                drawnow;
                if isequal(file, 0)
                    % User canceled the dialog
                    uialert(app.UIFigure, "No File Selected", "File Import Cancelled");
                    if ~app.selectedFile
                        app.NocurrentfileLabel.Text = "No Current file";
                    else
                        app.NocurrentfileLabel.Text = app.selectedFile;
                    end
                else
                    fullPath = fullfile(path, file);

                    % If there was already a file imported, resets the display
                    if app.selectedFile
                        app.reset();
                    end
           
                    % Storing the File as a property
                    app.selectedFile = fullPath;

                    % Updating the current file label
                    app.NocurrentfileLabel.Text = fullPath;
                   
                    % Getting the sheets and verifying that it the correct
                    % worksheet
                    app.differentSheets = sheetnames(app.selectedFile);
                    worksheetSheets = ["Protocol page", "Paste Data",...
                            "Flux per volume", "Specific flux", "Specific flux (bc)",...
                            "FCR", "FCR (bc)", "Protocol data (source)"];
                    worksheetSheets = worksheetSheets';
                    
                    if ~all(app.differentSheets == worksheetSheets)
                        uialert(app.UIFigure, "The imported file isn't a valid Ouroboros Workbook", "Invalid File");
                    else
                        % Making sure the protocol is the correct protocol
                        protocolDataTable = readtable(fullPath, 'Sheet', "Protocol page");
                        protocolInfo = protocolDataTable{6,7};
                        if ~isequal(protocolInfo, "SUIT-011_O2_pfi_D024")
                            uialert(app.UIFigure, "Unfortunately this GUI does not support this protocol, " + ...
                                "please use a SUIT-011 protocol", "Incorrect protocol");
                        else

                            % Filtering the visible sheets in the drop down
                            % to just the ones that can be graphed
                            app.differentSheets = app.differentSheets(3:7);
             
                            % Creating the Sheet Selection Dropdown and updating
                            % visibility
                            app.ImportSelectaSheetPanel.Visible = "on";
                            app.ImportSheetsDropDown.Visible = "on";
                            app.ImportSheetsDropDown.Enable = "on";
                            app.ImportSheetsDropDown.Items = app.differentSheets;
                            app.GeneralPlotSheetSelection.Items = app.differentSheets;
                            app.ComponentPlotSheetSelection.Items = app.differentSheets;
                            app.ImportSheetsDropDown.Value = app.ImportSheetsDropDown.Items(1);
                            DifferentSheetsDropDownValueChanged(app, struct('Value', app.ImportSheetsDropDown.Value))
                            app.SheetPreviewPanel.Visible = "on";

                            % Updating the tab visibility because there was an imported
                            % file
                            app.GotoComplexPlotsButton.Visible = "on";
                            app.ExportforJamoviButton.Visible ="on";
                            app.GotoGeneralBarPlotButton.Visible = "on";
                            app.GridGeneralBarPlotGridLayout.Visible =  "on";
                            app.ComponentPlotGridLayout.Visible = "on";


                        end
                    end
                end
        end

        % Value changed function: ComponentPlotSheetSelection, 
        % ...and 2 other components
        function DifferentSheetsDropDownValueChanged(app, event)
            % Updates the various sheet drop downs across the different
            % tabs to match
            currentSheet = event.Value;

            % Throws an error if the app somehow no longer has a file
            % imported
            if isempty(app.selectedFile)
                uialert(app.UIFigure, "No file selected yet", "Error");
                return;
            end 

            app.GeneralPlotSheetSelection.Value = currentSheet;
            app.ImportSheetsDropDown.Value = currentSheet;
            app.ComponentPlotSheetSelection.Value = currentSheet;
            % Updates the ETS checkbox based on the sheet selected
            if contains(currentSheet, "Specific flux")
                app.ETSCheckBox.Visible = "on";
            else
                app.ETSCheckBox.Visible = "off";
            end
            
            % Creates a preview for the first 10 rows
            previewData = readtable(app.selectedFile,'Sheet', currentSheet,'ReadVariableNames',true);
            numRows = min(10, height(previewData));
            app.PreviewData.Data = previewData(1:numRows, :);

            % Runs the importing function on the sheet
            app.metaData = importingO2K(app, app.selectedFile, app.ImportSheetsDropDown.Value);

        end

        % Button pushed function: GeneralGeneratePlotButton
        function GeneralGeneratePlotButtonPushed(app, event)
            
            % Data Cleaning and Plotting functions
            app.dataTable = generalPlotDataCleaning(app, app.metaData, app.ImportSheetsDropDown.Value);
            generalPlotGeneration(app, app.dataTable, app.ImportSheetsDropDown.Value, app.General_UIAxes);

            % Set the axis to be visible & update the active axes now that
            % this one has something drawn on it
            app.General_UIAxes.Visible = 'on';
            app.GeneralExportPlotButton.Enable = 'on';
        end

        % Value changed function: PlotMultipleLinesCheckBox
        function PlotMultipleLinesCheckBoxValueChanged(app, event)
            value = app.PlotMultipleLinesCheckBox.Value;
            % If it was turned off, reset the lines drawn on the plot
            if ~value
                resetGeneralPlot(app, []);
            end
            
        end

        % Value changed function: ShowErrorBarsCheckBox
        function ShowErrorBarsCheckBoxValueChanged(app, event)
            value = app.ShowErrorBarsCheckBox.Value;
            % If the app.barChartHandles property has something within it
            if ~isempty(app.errorBarHandles)
                if value % if the value is now true, set the error bars to
                    % be visible
                    for i = 1:numel(app.errorBarHandles)
                        currentBar = app.errorBarHandles(i);
                        currentBar.Visible = 'on';
                    end
                else % if it's set to false, hide the error bars
                    for i = 1:numel(app.errorBarHandles)
                        currentBar = app.errorBarHandles(i);
                        currentBar.Visible = 'off';
                    end
                end
            end
        end

        % Button pushed function: ComponentsGeneratePlotButton
        function ComponentsGeneratePlotButtonPushed(app, event)
            if app.selectedFile ~= false % Only runs as long as there's a selected file within the app
                app.componentUIAxesArray = findobj(app.ComponentPlotsTab, 'Tag', 'UIAxes'); % Find UIaxes objects within the components tab
                % Cleaning and Graphing the data for the component plots
                cleanComplexData = componentPlotDataCleaning(app, app.metaData);
                app.componentPlotHandles = complexPlotGeneration(app, cleanComplexData, app.componentUIAxesArray);
                % Calling the activeComponentAxes updator function now that more
                % lines have been plotted
                activeComponentAxesUpdator(app);
                app.ComponentsExportPlotButton.Enable = 'on';
                if numel(app.activeComponentAxes) > 1 % Updates the button text if more than one plot are being graphed
                    app.ComponentsExportPlotButton.Text = "Export" + newline + "Plots";
                else
                    app.ComponentsExportPlotButton.Text = "Export" + newline + "Plot";
                end
            end

        end

        % Value changed function: ComponentsDisplayListBox
        function ComponentsDisplayListBoxValueChanged(app, event)
            value = app.ComponentsDisplayListBox.Value;
            app.currentComponents = value; % Updates app.currentComponents based on the selected components held in value

            if ~isempty(value) && ~isempty(app.CohortsDisplayListBox.Value)
                app.ComponentsGeneratePlotButton.Enable = 'on';
            else
                app.ComponentsGeneratePlotButton.Enable = 'off';
            end
        end

        % Button pushed function: GotoGeneralBarPlotButton
        function GotoGeneralBarPlotButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.GeneralBarPlotTab; % Goes to the GeneralBarPlot Tab
        end

        % Button pushed function: GotoComplexPlotsButton
        function GotoComplexPlotsButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.ComponentPlotsTab; % Goes to the ComponentPlots Tab
        end

        % Value changed function: CohortsDisplayListBox
        function CohortsDisplayListBoxValueChanged(app, event)
            value = app.CohortsDisplayListBox.Value;
            if ~isempty(value) && ~isempty(app.ComponentsDisplayListBox.Value)
                app.ComponentsGeneratePlotButton.Enable = 'on';
            else
                app.ComponentsGeneratePlotButton.Enable = 'off';
            end
        end

        % Button pushed function: GeneralExportPlotButton
        function GeneralExportPlotButtonPushed(app, event)
            % Grabs the title of the currently plotted sheet and sanitizes
            % it for saving it as a file
            currentTitle = app.General_UIAxes.Title.String;
            sanitizedTitle = regexprep(currentTitle, '[\/:*?"<>|]', '_');
            sanitizedTitle = strrep(sanitizedTitle, ' ', '_');
            
            % Letting the user select where to save the file
            [file, path] = uiputfile({'*.png';'*.jpg';'*.pdf'}, 'Save Plot As', [sanitizedTitle, '.png']);
            if isequal(file,0) || isequal(path,0)
                return; % user canceled
            end
            
            % Exporting and saving the file
            filename = fullfile(path, file);
            app.General_UIAxes.Toolbar.Visible = "off";
            exportgraphics(app.General_UIAxes, filename, 'Resolution', 300);
            app.General_UIAxes.Toolbar.Visible = "on";

            % Opening the folder the file is in
            folder = fileparts(filename);   % get folder path

            if ispc
                winopen(folder);            % Windows
            elseif ismac
                system(['open "', folder, '"']);  % macOS
            else
                system(['xdg-open "', folder, '"']);  % Linux
            end
        end

        % Button pushed function: ComponentsExportPlotButton
        function ComponentsExportPlotButtonPushed(app, event)
            filename = cell(1, numel(app.activeComponentAxes));
            for i = 1:numel(app.activeComponentAxes)
                currentTitle = app.activeComponentAxes(i).Title.String;
                sanitizedTitle = regexprep(currentTitle, '[\/:*?"<>|]', '_');
                sanitizedTitle = strrep(sanitizedTitle, ' ', '_');

                % Letting the user select where to save the file
                [file, path] = uiputfile({'*.png';'*.jpg';'*.pdf'}, 'Save Plot As', [sanitizedTitle, '.png']);
                if isequal(file,0) || isequal(path,0)
                    continue; % user canceled, enables the user to have graphed multiple components but only export one
                end

                % Exporting and saving the file
                filename{i} = fullfile(path, file);
                % Loop over axes (assuming i is defined)
                ax = app.activeComponentAxes(i);

                % Save original attributes to restore later
                origToolbarVisible = ax.Toolbar.Visible;
                origXLim = ax.XLim;
                origYLim = ax.YLim;
                origXGrid = ax.XGrid;
                origYGrid = ax.YGrid;
                origPBA = ax.PlotBoxAspectRatio;
                origXColor = ax.XColor;
                origYColor = ax.YColor;
                origTitleColor = ax.Title.Color;
                origXLabelColor = ax.XLabel.Color;
                origYLabelColor = ax.YLabel.Color;


                sc = findobj(ax.Children, 'Type', 'Scatter');

                % store original visibility
                origVisibility = get(sc,'Visible');

                % hide them
                set(sc,'Visible','off');

                % Turn off toolbar and grid for export
                ax.Toolbar.Visible = "off";
                ax.XGrid = 'off';
                ax.YGrid = 'off';
                ax.XLabel.Visible = 'off';

                ax.PlotBoxAspectRatio = [1 1.5 1];

                % Compute X limits with ±5% padding around all scatter XData
                allX = [];
                for k = 1:numel(sc)
                    allX = [allX; sc(k).XData(:)];
                end
                pad = 0.3 * range(allX);
                ax.XLim = [min(allX)-pad, max(allX)+pad];

                ax.XColor = 'k';
                ax.YColor = 'k';
                ax.Title.Color = 'k';
                ax.XLabel.Color = 'k';
                ax.YLabel.Color = 'k';

                % Add temporary black dots on top (same positions as existing scatter plots)
                tmpSc = gobjects(1, numel(sc));
                for k = 1:numel(sc)
                    tmpSc(k) = scatter(ax, sc(k).XData, sc(k).YData, (sc(k).SizeData * .5), 'k', 'filled');
                end

                % Export graphics
                exportgraphics(ax, filename{i}, ...
                       'Resolution', 300, ...
                       'BackgroundColor', 'w', ...
                       'Padding', 50);

                % Delete temporary scatter dots
                delete(tmpSc);

                % Restore visibility
                for k = 1:numel(sc)
                    sc(k).Visible = origVisibility{k};
                end

                % Restore original axes attributes
                ax.XLim = origXLim;
                ax.YLim = origYLim;
                ax.XGrid = origXGrid;
                ax.YGrid = origYGrid;
                ax.Toolbar.Visible = origToolbarVisible;
                ax.PlotBoxAspectRatio = origPBA;
                ax.XColor = origXColor;
                ax.YColor = origYColor;
                ax.Title.Color = origTitleColor;
                ax.XLabel.Color = origXLabelColor;
                ax.YLabel.Color = origYLabelColor;
                ax.XLabel.Visible = 'on';

            end
            
            for i = 1:numel(filename) % Opening the folders the file are in
                if ~isempty(filename{i})
                    
                    folder = char(fileparts(filename{i}));   % get folder path

                    if ispc
                        winopen(folder);            % Windows
                    elseif ismac
                        system(['open "', folder, '"']);  % macOS
                    else
                        system(['xdg-open "', folder, '"']);  % Linux
                    end
                end
            end
        end

        % Button pushed function: ExportforJamoviButton
        function ExportforJamoviButtonPushed(app, event)
           
            fixedCols = {'Date', 'Cohort', 'Sample #'};
            % Importing and cleaning Specific Flux (bc)
            roughSFData = app.importingO2K(app.selectedFile, "Specific flux (bc)");
            cleanSFData = roughSFData(3:end, [fixedCols, roughSFData.Properties.VariableNames(15:end)]);


            % Importing and cleaning FCR (bc)
            roughFCRData = app.importingO2K(app.selectedFile, "FCR (bc)");
            cleanFCRData = roughFCRData(3:end, [fixedCols, roughFCRData.Properties.VariableNames(15:end)]);

            % Samples and Cohorts are shared across the sheets
            uniqueSamples = unique(cleanSFData.('Sample #'));
            uniqueCohorts = unique(cleanSFData.('Cohort'));

            V = width(cleanSFData(:, 4:end));
            C = length(uniqueCohorts);
            totalCols = V * C;

            dataSFSheet = array2table(nan(length(uniqueSamples), totalCols));
            dataFCRSheet = array2table(nan(length(uniqueSamples), totalCols));

            infoSheet = cell2table(cell(length(uniqueSamples), 2));

            % Iteration to pivot the data into the Jamovi format
            for i = 1:length(uniqueSamples)
                currentSample = uniqueSamples(i);

                sampleSFData = cleanSFData(cleanSFData.('Sample #') == currentSample, :);
                sampleFCRData = cleanFCRData(cleanFCRData.('Sample #') == currentSample, :);

                % Filling in the infosheet
                sampleSFData.Date = cellstr(string(sampleSFData.Date));
                infoSheet(i,1) = sampleSFData(1,'Date');
                sampleSFData.('Sample #') = cellstr(string((sampleSFData.('Sample #'))));
                infoSheet(i, 2) = sampleSFData(1, 'Sample #');

                V = width(sampleSFData(:, 4:end));
                C = length(uniqueCohorts);

                for k = 1:C
                    currentCohort = uniqueCohorts(k);

                    % Extracting Rows for this Cohort
                    cohortSFData = sampleSFData(strcmp(sampleSFData.('Cohort'), currentCohort), 4:end);
                    cohortFCRData = sampleFCRData(strcmp(sampleFCRData.('Cohort'), currentCohort), 4:end);


                    % Handle missing cohorts for this sample
                    if isempty(cohortSFData)
                        cohortSFData = array2table(nan(1,V));
                        cohortSFData.Properties.VariableNames = cleanSFData.Properties.VariableNames(4:end);
                    end
                    if isempty(cohortFCRData)
                        cohortFCRData = array2table(nan(1,V));
                        cohortFCRData.Properties.VariableNames = cleanFCRData.Properties.VariableNames(4:end);
                    end

                    % New Names
                    sfNames = strcat(currentCohort, "_", cohortSFData.Properties.VariableNames, "_SpecificFlux");
                    fcrNames = strcat(currentCohort, "_", cohortFCRData.Properties.VariableNames, "_FCR");
                    
                    % Interleaved assignment
                    for v = 1:V
                        colIndex = (v-1)*C + k;   % interleaving formula

                        sfVal = cohortSFData{1, v};
                        fcrVal = cohortFCRData{1, v};

                        % Convert anything non-numeric into a numeric value
                        if iscell(sfVal),  sfVal = str2double(sfVal);  end
                        if iscell(fcrVal), fcrVal = str2double(fcrVal); end

                        % Final numeric assignment (NaN for invalid or empty)
                        dataSFSheet{i, colIndex} = double(sfVal);
                        dataFCRSheet{i, colIndex} = double(fcrVal);


                        dataSFSheet.Properties.VariableNames{colIndex} = sfNames{v};
                        dataFCRSheet.Properties.VariableNames{colIndex} = fcrNames{v};
                    end
                    
                end
            end


            % Getting the property names for the first two columns
            infoSheet.Properties.VariableNames = sampleSFData.Properties.VariableNames([1,3]);

            % Concatenating all of the tables together
            jamoviSheet = [infoSheet, dataSFSheet, dataFCRSheet];


            % Converting the strings to numeric
            %jamoviSheet{:, 2:end} = num2cell(str2double(jamoviSheet{:, 2:end}));

            
            % Export Code
            
            currentTitle = roughSFData.Project{3};
            sanitizedTitle = regexprep(currentTitle, '[\/:*?"<>|]', '_');
            sanitizedTitle = strrep(sanitizedTitle, ' ', '_');
            
            % Letting the user select where to save the file
            [file, path] = uiputfile({'*.xlsx'}, 'Save Plot As', [strcat(sanitizedTitle,'_Jamovi'), '.xlsx']);
            if isequal(file,0) || isequal(path,0)
                return; % user canceled
            end
            
            % Exporting and saving the file
            filename = fullfile(path, file);
            writetable(jamoviSheet, filename);

            % Opening the folder the file is in
            folder = fileparts(filename);   % get folder path

            if ispc
                winopen(folder);            % Windows
            elseif ismac
                system(['open "', folder, '"']);  % macOS
            else
                system(['xdg-open "', folder, '"']);  % Linux
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.TabGroup = uitabgroup(app.GridLayout);
            app.ImportTab = uitab(app.TabGroup);
            app.ImportTab.Title = 'Import';
            app.ImportTabGridLayout = uigridlayout(app.ImportTab);
            app.ImportafileButton = uibutton(app.ImportTabGridLayout, 'push');
            app.ImportafileButton.ButtonPushedFcn = createCallbackFcn(app, @ImportafileButtonPushed, true);
            app.ImportafileButton.Text = 'Import a file';
            app.GotoGeneralBarPlotButton = uibutton(app.ImportTabGridLayout, 'push');
            app.GotoGeneralBarPlotButton.ButtonPushedFcn = createCallbackFcn(app, @GotoGeneralBarPlotButtonPushed, true);
            app.GotoGeneralBarPlotButton.Visible = 'off';
            app.GotoGeneralBarPlotButton.Text = 'Go to General Bar Plot';
            app.GotoComplexPlotsButton = uibutton(app.ImportTabGridLayout, 'push');
            app.GotoComplexPlotsButton.ButtonPushedFcn = createCallbackFcn(app, @GotoComplexPlotsButtonPushed, true);
            app.GotoComplexPlotsButton.Visible = 'off';
            app.GotoComplexPlotsButton.Text = 'Go to Complex Plots';
            app.ExportforJamoviButton = uibutton(app.ImportTabGridLayout, 'push');
            app.ExportforJamoviButton.ButtonPushedFcn = createCallbackFcn(app, @ExportforJamoviButtonPushed, true);
            app.ExportforJamoviButton.Visible = 'off';
            app.ExportforJamoviButton.Text = 'Export for Jamovi';
            app.CurrentFilePanel = uipanel(app.ImportTabGridLayout);
            app.CurrentFilePanel.Title = 'Current File:';
            app.CurrentFilePanelGridLayout = uigridlayout(app.CurrentFilePanel);
            app.NocurrentfileLabel = uilabel(app.CurrentFilePanelGridLayout);
            app.NocurrentfileLabel.Text = 'No current file';
            app.ImportSelectaSheetPanel = uipanel(app.ImportTabGridLayout);
            app.ImportSelectaSheetPanel.Title = 'Select a Sheet:';
            app.ImportSelectaSheetPanel.Visible = 'off';
            app.ImportSelectaSheetPanelGridLayout = uigridlayout(app.ImportSelectaSheetPanel);
            app.ImportSheetsDropDown = uidropdown(app.ImportSelectaSheetPanelGridLayout);
            app.ImportSheetsDropDown.Items = {};
            app.ImportSheetsDropDown.ValueChangedFcn = createCallbackFcn(app, @DifferentSheetsDropDownValueChanged, true);
            app.ImportSheetsDropDown.Enable = 'off';
            app.ImportSheetsDropDown.Visible = 'off';
            app.ImportSheetsDropDown.Placeholder = '                              Select a Sheet';
            app.ImportSheetsDropDown.Value = {};
            app.SheetPreviewPanel = uipanel(app.ImportTabGridLayout);
            app.SheetPreviewPanel.Title = 'Sheet Preview:';
            app.SheetPreviewPanel.Visible = 'off';
            app.SheetPreviewPanelGridLayout = uigridlayout(app.SheetPreviewPanel);
            app.PreviewData = uitable(app.SheetPreviewPanelGridLayout);
            app.PreviewData.ColumnName = '';
            app.PreviewData.RowName = {};
            app.GeneralBarPlotTab = uitab(app.TabGroup);
            app.GeneralBarPlotTab.Title = 'General Bar Plot';
            app.GridGeneralBarPlotGridLayout = uigridlayout(app.GeneralBarPlotTab);
            app.GridGeneralBarPlotGridLayout.Visible = 'off';
            app.General_UIAxes = uiaxes(app.GridGeneralBarPlotGridLayout);
            title(app.General_UIAxes, 'Title')
            xlabel(app.General_UIAxes, 'X')
            ylabel(app.General_UIAxes, 'Y')
            zlabel(app.General_UIAxes, 'Z')
            app.General_UIAxes.Tag = 'UIAxes';
            app.GeneralPlotOptionsPanel = uipanel(app.GridGeneralBarPlotGridLayout);
            app.GeneralPlotOptionsGridLayout = uigridlayout(app.GeneralPlotOptionsPanel);
            app.GeneralGeneratePlotButton = uibutton(app.GeneralPlotOptionsGridLayout, 'push');
            app.GeneralGeneratePlotButton.ButtonPushedFcn = createCallbackFcn(app, @GeneralGeneratePlotButtonPushed, true);
            app.GeneralGeneratePlotButton.Text = {'Generate'; 'Plot'};
            app.GeneralExportPlotButton = uibutton(app.GeneralPlotOptionsGridLayout, 'push');
            app.GeneralExportPlotButton.ButtonPushedFcn = createCallbackFcn(app, @GeneralExportPlotButtonPushed, true);
            app.GeneralExportPlotButton.Enable = 'off';
            app.GeneralExportPlotButton.Text = {'Export'; 'Plot'};
            app.PlotOptionsPanel = uipanel(app.GeneralPlotOptionsGridLayout);
            app.PlotOptionsPanel.Title = 'Plot Options';
            app.PlotOptionsPanelGridLayout = uigridlayout(app.PlotOptionsPanel);
            app.ShowErrorBarsCheckBox = uicheckbox(app.PlotOptionsPanelGridLayout);
            app.ShowErrorBarsCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowErrorBarsCheckBoxValueChanged, true);
            app.ShowErrorBarsCheckBox.Text = 'Show Error Bars';
            app.PlotMultipleLinesCheckBox = uicheckbox(app.PlotOptionsPanelGridLayout);
            app.PlotMultipleLinesCheckBox.ValueChangedFcn = createCallbackFcn(app, @PlotMultipleLinesCheckBoxValueChanged, true);
            app.PlotMultipleLinesCheckBox.Text = 'Plot Multiple Lines';
            app.GeneralPlotSelectaSheetPanel = uipanel(app.GeneralPlotOptionsGridLayout);
            app.GeneralPlotSelectaSheetPanel.Title = 'Select a Sheet:';
            app.GeneralPlotSelectaSheetPanelGridLayout = uigridlayout(app.GeneralPlotSelectaSheetPanel);
            app.GeneralPlotSheetSelection = uidropdown(app.GeneralPlotSelectaSheetPanelGridLayout);
            app.GeneralPlotSheetSelection.Items = {};
            app.GeneralPlotSheetSelection.ValueChangedFcn = createCallbackFcn(app, @DifferentSheetsDropDownValueChanged, true);
            app.GeneralPlotSheetSelection.Placeholder = 'Select a Sheet';
            app.GeneralPlotSheetSelection.Value = {};
            app.AdditionalComplexesPanel = uipanel(app.GeneralPlotOptionsGridLayout);
            app.AdditionalComplexesPanel.Title = 'Additional Complexes:';
            app.AdditionalComplexesPanelGridLayout = uigridlayout(app.AdditionalComplexesPanel);
            app.ComplexINetOXPHOSCheckBox = uicheckbox(app.AdditionalComplexesPanelGridLayout);
            app.ComplexINetOXPHOSCheckBox.Text = 'Complex I Net OXPHOS';
            app.ComplexIILinkedETSCapacityCheckBox = uicheckbox(app.AdditionalComplexesPanelGridLayout);
            app.ComplexIILinkedETSCapacityCheckBox.Text = 'Complex II Linked ETS Capacity';
            app.TotalNetOXPHOSCheckBox = uicheckbox(app.AdditionalComplexesPanelGridLayout);
            app.TotalNetOXPHOSCheckBox.Text = 'Total Net OXPHOS';
            app.ETSCheckBox = uicheckbox(app.AdditionalComplexesPanelGridLayout);
            app.ETSCheckBox.Text = 'Electron Transport System';
            app.ComponentPlotsTab = uitab(app.TabGroup);
            app.ComponentPlotsTab.Title = 'Component Plots';
            app.ComponentPlotGridLayout = uigridlayout(app.ComponentPlotsTab);
            app.ComponentPlotGridLayout.Visible = 'off';
            app.Component_UIAxes_7 = uiaxes(app.ComponentPlotGridLayout);
            title(app.Component_UIAxes_7, 'Title')
            xlabel(app.Component_UIAxes_7, 'X')
            ylabel(app.Component_UIAxes_7, 'Y')
            zlabel(app.Component_UIAxes_7, 'Z')
            app.Component_UIAxes_7.Tag = 'UIAxes';
            app.Component_UIAxes_6 = uiaxes(app.ComponentPlotGridLayout);
            title(app.Component_UIAxes_6, 'Title')
            xlabel(app.Component_UIAxes_6, 'X')
            ylabel(app.Component_UIAxes_6, 'Y')
            zlabel(app.Component_UIAxes_6, 'Z')
            app.Component_UIAxes_6.Tag = 'UIAxes';
            app.Component_UIAxes_5 = uiaxes(app.ComponentPlotGridLayout);
            title(app.Component_UIAxes_5, 'Title')
            xlabel(app.Component_UIAxes_5, 'X')
            ylabel(app.Component_UIAxes_5, 'Y')
            zlabel(app.Component_UIAxes_5, 'Z')
            app.Component_UIAxes_5.Tag = 'UIAxes';
            app.Component_UIAxes_4 = uiaxes(app.ComponentPlotGridLayout);
            title(app.Component_UIAxes_4, 'Title')
            xlabel(app.Component_UIAxes_4, 'X')
            ylabel(app.Component_UIAxes_4, 'Y')
            zlabel(app.Component_UIAxes_4, 'Z')
            app.Component_UIAxes_4.Tag = 'UIAxes';
            app.Component_UIAxes_3 = uiaxes(app.ComponentPlotGridLayout);
            title(app.Component_UIAxes_3, 'Title')
            xlabel(app.Component_UIAxes_3, 'X')
            ylabel(app.Component_UIAxes_3, 'Y')
            zlabel(app.Component_UIAxes_3, 'Z')
            app.Component_UIAxes_3.Tag = 'UIAxes';
            app.Component_UIAxes_2 = uiaxes(app.ComponentPlotGridLayout);
            title(app.Component_UIAxes_2, 'Title')
            xlabel(app.Component_UIAxes_2, 'X')
            ylabel(app.Component_UIAxes_2, 'Y')
            zlabel(app.Component_UIAxes_2, 'Z')
            app.Component_UIAxes_2.Tag = 'UIAxes';
            app.Component_UIAxes_1 = uiaxes(app.ComponentPlotGridLayout);
            title(app.Component_UIAxes_1, 'Title')
            xlabel(app.Component_UIAxes_1, 'X')
            ylabel(app.Component_UIAxes_1, 'Y')
            zlabel(app.Component_UIAxes_1, 'Z')
            app.Component_UIAxes_1.Tag = 'UIAxes';
            app.ComponentsPlotPanel = uipanel(app.ComponentPlotGridLayout);
            app.ComponentsPanelGridLayout = uigridlayout(app.ComponentsPlotPanel);
            app.ComponentsGeneratePlotButton = uibutton(app.ComponentsPanelGridLayout, 'push');
            app.ComponentsGeneratePlotButton.ButtonPushedFcn = createCallbackFcn(app, @ComponentsGeneratePlotButtonPushed, true);
            app.ComponentsGeneratePlotButton.Enable = 'off';
            app.ComponentsGeneratePlotButton.Text = {'Generate'; 'Plot'};
            app.ComponentsExportPlotButton = uibutton(app.ComponentsPanelGridLayout, 'push');
            app.ComponentsExportPlotButton.ButtonPushedFcn = createCallbackFcn(app, @ComponentsExportPlotButtonPushed, true);
            app.ComponentsExportPlotButton.Enable = 'off';
            app.ComponentsExportPlotButton.Text = {'Export'; 'Plot'};
            app.ComponentPlotsSelectaSheetPanel = uipanel(app.ComponentsPanelGridLayout);
            app.ComponentPlotsSelectaSheetPanel.Title = 'Select a Sheet:';
            app.ComponentPlotsSelectaSheetPanelGridLayout = uigridlayout(app.ComponentPlotsSelectaSheetPanel);
            app.ComponentPlotSheetSelection = uidropdown(app.ComponentPlotsSelectaSheetPanelGridLayout);
            app.ComponentPlotSheetSelection.ValueChangedFcn = createCallbackFcn(app, @DifferentSheetsDropDownValueChanged, true);
            app.ComplexesDisplayPanel = uipanel(app.ComponentsPanelGridLayout);
            app.ComplexesDisplayPanel.Title = 'Complexes to Display:';
            app.ComplexesDisplayPanelGrdidLayout = uigridlayout(app.ComplexesDisplayPanel);
            app.ComponentsDisplayListBox = uilistbox(app.ComplexesDisplayPanelGrdidLayout);
            app.ComponentsDisplayListBox.Items = {'Complex I-linked LEAK Respiration', 'Complex I-linked OXPHOS Capacity', 'Total OXPHOS Capacity', 'Complex I Net OXPHOS', 'Complex II linked ETS Capacity', 'Total Net OXPHOS', 'Electron Transport System Capacity'};
            app.ComponentsDisplayListBox.Multiselect = 'on';
            app.ComponentsDisplayListBox.ValueChangedFcn = createCallbackFcn(app, @ComponentsDisplayListBoxValueChanged, true);
            app.ComponentsDisplayListBox.Tooltip = {'Control/Cmd+Click to select multiple'};
            app.ComponentsDisplayListBox.Value = {};
            app.CohortsDisplayPanel = uipanel(app.ComponentsPanelGridLayout);
            app.CohortsDisplayPanel.Title = 'Cohorts to Display:';
            app.CohortsDisplayPanelGridLayout = uigridlayout(app.CohortsDisplayPanel);
            app.CohortsDisplayListBox = uilistbox(app.CohortsDisplayPanelGridLayout);
            app.CohortsDisplayListBox.Items = {};
            app.CohortsDisplayListBox.Multiselect = 'on';
            app.CohortsDisplayListBox.ValueChangedFcn = createCallbackFcn(app, @CohortsDisplayListBoxValueChanged, true);
            app.CohortsDisplayListBox.Tooltip = {'Control/Cmd+Click to select multiple'};
            app.CohortsDisplayListBox.Value = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = O2Kv09

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end